classdef OpenStride < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ModeSwitch                      matlab.ui.control.StateButton
        OperatingSystemLabel            matlab.ui.control.Label
        TabGroupL                       matlab.ui.container.TabGroup
        RecordL                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CalibrateButton                 matlab.ui.control.StateButton
        ExportSettingsPanel             matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SelectExperimentalAnimalButtonGroup  matlab.ui.container.ButtonGroup
        MouseButton                     matlab.ui.control.RadioButton
        RatButton                       matlab.ui.control.RadioButton
        OutputPathLabel                 matlab.ui.control.Label
        FileNameHistory                 matlab.ui.control.DropDown
        Note                            matlab.ui.control.TextArea
        NoteLabel_2                     matlab.ui.control.Label
        customFileName                  matlab.ui.control.EditField
        SelectOutputFolderButton        matlab.ui.control.StateButton
        OutputFileNameLabel             matlab.ui.control.Label
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        SampleFrequencyDropDown         matlab.ui.control.DropDown
        SampleFrequencyLabel            matlab.ui.control.Label
        ofdataLabel                     matlab.ui.control.Label
        DiscardUnitDropDown             matlab.ui.control.DropDown
        DiscardSpinner                  matlab.ui.control.Spinner
        DiscardCheckBox                 matlab.ui.control.CheckBox
        AutoStopUnitDropDown            matlab.ui.control.DropDown
        AutoStopSpinner                 matlab.ui.control.Spinner
        AutoStopCheckBox                matlab.ui.control.CheckBox
        StartRecordButton               matlab.ui.control.StateButton
        AnalysisL                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        OutputFolderNameEditField       matlab.ui.control.EditField
        OutputFolderNameEditFieldLabel  matlab.ui.control.Label
        ResultOutputLabel               matlab.ui.control.Label
        ResultOutputButton              matlab.ui.control.StateButton
        Panel_3                         matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        RemoveButton                    matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        FileList                        matlab.ui.control.ListBox
        Panel_2                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        higherTremorFrequency           matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        lowerTremorFrequency            matlab.ui.control.NumericEditField
        FrequencyRangeLabel             matlab.ui.control.Label
        TremorCheckBox                  matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Durationthreshold               matlab.ui.control.NumericEditField
        DurationthresholdsEditFieldLabel  matlab.ui.control.Label
        Speedthreshold                  matlab.ui.control.NumericEditField
        SpeedthresholdcmsEditFieldLabel  matlab.ui.control.Label
        LowMobilityBoutsCheckBox        matlab.ui.control.CheckBox
        StartAnalysisButton             matlab.ui.control.StateButton
        Panel_                          matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        DistanceSpeedCheckBox           matlab.ui.control.CheckBox
        SOGPanel                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SurfacePlotCheckBox             matlab.ui.control.CheckBox
        yMaximum                        matlab.ui.control.Spinner
        yDirectionMaximumAbsoluteValueofMovementSpinnerLabel  matlab.ui.control.Label
        xMaximum                        matlab.ui.control.Spinner
        xDirectionMaximumAbsoluteValueofMovementSpinnerLabel  matlab.ui.control.Label
        SoGThreshold                    matlab.ui.control.Spinner
        StraightnessofGaitThresholdcmLabel  matlab.ui.control.Label
        AtaxiaCheckBox                  matlab.ui.control.CheckBox
        TabGroupR                       matlab.ui.container.TabGroup
        RecordR                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        TextUpdate                      matlab.ui.control.Label
        AnalysisR                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Label_4                         matlab.ui.control.Label
        AtaxiaButton                    matlab.ui.control.Button
        LowMobilityBoutsButton          matlab.ui.control.Button
        DistanceSpeedButton             matlab.ui.control.Button
        TremorButton                    matlab.ui.control.Button
        DataFileLabel                   matlab.ui.control.Label
        DataFilesListBox                matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        ResultDefult                    matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ProgressLabel                   matlab.ui.control.Label
        ResultDefult_2                  matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        ProgressFig                     matlab.ui.control.Image
        complete                        matlab.ui.control.Image
        AnalysisProgressLabel           matlab.ui.control.Label
        TremorTab                       matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        TremorResultLabel               matlab.ui.control.Label
        TremorAxes                      matlab.ui.control.UIAxes
        AtaxiaTab                       matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        listBoxTitle                    matlab.ui.control.Label
        AtaxiaListBox                   matlab.ui.control.ListBox
        averageMovementRatio            matlab.ui.control.Label
        AtaxiaAxes                      matlab.ui.control.UIAxes
        LowMobilityBoutsTab             matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        LMBtext                         matlab.ui.control.Label
        LBMplot                         matlab.ui.control.UIAxes
        DistanceSpeedTab                matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        MovementVsTime                  matlab.ui.control.UIAxes
        speedVsTime                     matlab.ui.control.UIAxes
        positionVsTime                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        currentFolder % Root folder of the app; used to construct paths for saving/loading files
        outputRecordFolderPath % Folder path for recording data writing
        outputAnalysisFolderPath % Parent Folder path for analysis result writing
        resultStoragePath % Actual Folder Holds all Analysis Result
        data
        analysismode
    end
    
    properties (Access = private)
        AutoStopTimer  % timer object for auto-stop
    end

    methods (Access = public)

        function updateListbox(app,list)
            app.DataFilesListBox.Items = list;
        end
    end

    methods (Access = private)

        function updateButtonStates(app)
            app.AtaxiaButton.Enable = 'on'; if ~app.AtaxiaCheckBox.Value, app.AtaxiaButton.Enable = 'off'; end
            app.LowMobilityBoutsButton.Enable = 'on'; if ~app.LowMobilityBoutsCheckBox.Value, app.LowMobilityBoutsButton.Enable = 'off'; end
            app.DistanceSpeedButton.Enable = 'on'; if ~app.DistanceSpeedCheckBox.Value, app.DistanceSpeedButton.Enable = 'off'; end
            app.TremorButton.Enable = 'on'; if ~app.TremorCheckBox.Value, app.TremorButton.Enable = 'off'; end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.complete.Visible ='off';
            app.ProgressFig.Visible = 'off';
            app.ProgressLabel.Visible ='off';
            
            addpath(genpath(fileparts(mfilename('fullpath'))));
         
            % Set currentFolder to the folder where the app file is located
            app.currentFolder = fileparts(mfilename('fullpath'));

            userSettingPath = tempPath(app,'userSettings.mat');

            if isfile(userSettingPath)
                load(userSettingPath, 'userSettings');

                app.AutoStopCheckBox.Value = userSettings.AutoStop;
                app.AutoStopSpinner.Value = userSettings.AutoStopSpinner;
                app.AutoStopUnitDropDown.Value = userSettings.AutoStopUnit;

                app.DiscardCheckBox.Value = userSettings.Discard;
                app.DiscardSpinner.Value = userSettings.DiscardSpinner;
                app.DiscardUnitDropDown.Value = userSettings.DiscardUnit;

                animalButtons = app.SelectExperimentalAnimalButtonGroup.Buttons;

                for i = 1:length(animalButtons)
                    if strcmp(animalButtons(i).Text, userSettings.SelectedAnimal)
                        app.SelectExperimentalAnimalButtonGroup.SelectedObject = animalButtons(i);
                        break;
                    end
                end

                if ~isfield(userSettings, 'fileNameHistory')
                    userSettings.fileNameHistory = {};                                                
                end
                app.FileNameHistory.Items = userSettings.fileNameHistory;

                app.SampleFrequencyDropDown.Value = userSettings.SampleRate;

            end

        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)

            % Check if the toggle button is pressed (Value is true)
            if app.ModeSwitch.Value
                % When released, set the button text to indicate "Data Analysis" mode
                app.ModeSwitch.Text = 'Data Analysis';
                app.TabGroupL.SelectedTab = app.AnalysisL;
                app.TabGroupR.SelectedTab = app.AnalysisR;

            else
                % When pressed, set the button text to indicate "Data Acquisition" mode
                app.ModeSwitch.Text = 'Data Record';
                app.TabGroupL.SelectedTab = app.RecordL;
                app.TabGroupR.SelectedTab = app.RecordR;
            end

        end

        % Value changed function: StartRecordButton
        function StartRecordButtonValueChanged(app, event)

            % === Manual stop ===
            if app.StartRecordButton.Value == 0
                % Cancel auto-stop timer if it exists
                if isprop(app, 'AutoStopTimer') && ~isempty(app.AutoStopTimer) && isvalid(app.AutoStopTimer)
                    stop(app.AutoStopTimer);
                    delete(app.AutoStopTimer);
                    app.AutoStopTimer = [];
                end
                return;
            end

            % === Validation checks ===
            if app.StartRecordButton.Value == 0 || ...
                    ~checkAndRunCalibration(app) || ...
                    ~validateDiscardAndAutoStop(app)
                app.StartRecordButton.Value = 0;
                return;
            end

            if isempty(app.outputRecordFolderPath) || isequal(app.outputRecordFolderPath, 0)
                uiconfirm(app.UIFigure, ...
                    'Please select an output folder before proceeding.', ...
                    'Missing Folder', ...
                    'Options', {'OK'}, ...
                    'DefaultOption', 1, ...
                    'Icon', 'warning');
                app.StartRecordButton.Value = 0;
                app.SelectOutputFolderButton.BackgroundColor = [0.9, 0.4, 0.4];
                return;
            end

            % === Lock UI and update button text ===
            lock(app, app.StartRecordButton);
            app.StartRecordButton.Text = 'Stop Recording';

            % === Start actual recording ===
            startRecord(app);

            % === Append File Name to User Setting ===
            historyList = app.FileNameHistory.Items;
            historyList{end+1} = app.customFileName.Value;
            maxHistory = 10;

            if numel(historyList) > maxHistory
                historyList = historyList(end-maxHistory+1:end);
            end

            app.FileNameHistory.Items = historyList;

        end

        % Value changed function: CalibrateButton
        function CalibrateButtonValueChanged(app, event)

            app.CalibrateButton.Text = 'Calibrating ...';   % Update button text to indicate calibration is in progress
            lock(app,'all')                                 % Disable UI components to prevent user interaction during calibration

            uiconfirm(app.UIFigure, ...
                'Calibration will begin. Please do not move the plate.', ...
                'Calibration Warning', ...
                'Options', {'Sure, start now!', 'Cancel Calibration'}, ...
                'DefaultOption', 'Sure, start now!', ...
                'CancelOption', 'Cancel Calibration', ...
                'CloseFcn', @(src, event)runCalibrationScript(app, event));

            app.CalibrateButton.Value = 0;
            app.CalibrateButton.Text = 'Calibrate';
            unlock(app)

        end

        % Value changed function: SelectOutputFolderButton
        function SelectOutputFolderButtonValueChanged(app, event)

            app.SelectOutputFolderButton.BackgroundColor =[0.96,0.96,0.96];
            lock(app,'all')
            if app.SelectOutputFolderButton.Value == 1
                app.outputRecordFolderPath = uigetdir('C:\', 'Select Folder');
                figure(app.UIFigure)
            end

            if app.outputRecordFolderPath ~= 0
                app.OutputPathLabel.Text = app.outputRecordFolderPath;
            end

            unlock(app)

        end

        % Callback function
        function FileNameHistoryValueChanged(app, event)
            app.customFileName.Value = app.FileNameHistory.Value;          

        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            lock(app,'all')

            % Invoke the function to import and copy data files
            [files, path] = uigetfile({'*.csv';'*.lvm';'*.txt';'*.xls';'*.xlsx'}, 'Select One or More Files', 'MultiSelect', 'on');
            app.data = copyFile(app, files, path);

            unlock(app)

        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            updateFileList(app, 'up');
            app.FileListValueChanged();
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            updateFileList(app, 'down');
            app.FileListValueChanged();
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            updateFileList(app, 'remove');
            app.FileListValueChanged();

        end

        % Value changed function: FileList, FileNameHistory
        function FileListValueChanged(app, event)

            % Default: enable all control buttons
            app.MoveUpButton.Enable = 'on';
            app.MoveDownButton.Enable = 'on';
            app.RemoveButton.Enable = 'on';

            % Get the selected item (full file path as a string)
            selectedValue = app.FileList.Value;

            % Disable all buttons if no valid item is selected
            if isempty(selectedValue) || isequal(selectedValue, 'Please import data file(s)')
                app.MoveUpButton.Enable = 'off';
                app.MoveDownButton.Enable = 'off';
                app.RemoveButton.Enable = 'off';
                return;
            end

            % Find the index of the selected item in the ListBox
            selectedIndex = find(strcmp(app.FileList.Items, selectedValue));

            % Disable 'Move Up' button if the selected item is the first one
            if selectedIndex == 1
                app.MoveUpButton.Enable = 'off';
            end

            % Disable 'Move Down' button if the selected item is the last one
            if selectedIndex == numel(app.FileList.Items)
                app.MoveDownButton.Enable = 'off';
            end

        end

        % Value changed function: ResultOutputButton
        function ResultOutputButtonValueChanged(app, event)
            app.ResultOutputButton.BackgroundColor =[0.96,0.96,0.96];
            lock(app,'all')

            if app.ResultOutputButton.Value == 1
                app.outputAnalysisFolderPath = uigetdir('C:\', 'Select Folder');
                figure(app.UIFigure)
            end

            if app.outputAnalysisFolderPath ~= 0
                app.ResultOutputLabel.Text = app.outputAnalysisFolderPath;
            end

            unlock(app)

        end

        % Value changed function: StartAnalysisButton
        function StartAnalysisButtonValueChanged(app, event)
            
            % if app.StartRecordButton.Value == 0
            %     5
            %     return;
            % end

            lock(app,app.ResultDefult_2);
            
            selected = [
                app.DistanceSpeedCheckBox.Value,
                app.LowMobilityBoutsCheckBox.Value,
                app.DistanceSpeedCheckBox.Value,
                app.AtaxiaCheckBox.Value,
                app.TremorCheckBox.Value,
                ];

            if ~any(selected)
                uiconfirm(app.UIFigure, ...
                    'No analysis method has been selected. Please select at least one to continue.', ...
                    'No Method Selected', ...
                    'Icon', 'warning', ...
                    'Options', {'OK'}, ...
                    'DefaultOption', 1);
                unlock(app)
                return;
            end

            if isempty(app.outputAnalysisFolderPath) || isequal(app.outputAnalysisFolderPath, 0)
                uiconfirm(app.UIFigure, ...
                    'Please select an output folder before proceeding.', ...
                    'Missing Folder', ...
                    'Options', {'OK'}, ...
                    'DefaultOption', 1, ...
                    'Icon', 'warning');
                app.outputAnalysisFolderPath.Value = 0;
                app.ResultOutputButton.BackgroundColor = [0.9, 0.4, 0.4];
                unlock(app)
                return;
            end

            if length(app.data)>1
                message = {'You have imported multiple files.',...
                    'Would you like to analyse them separately or combine all files? ',...
                    'You can also reorganise the order of the files before combining.'};
                title = 'File Analysis Options';
                selection = uiconfirm(app.UIFigure, message, ...
                    title, ...
                    'Options', {'Analyze Separately', 'Combine Files', 'Cancel and Reorganise File Order'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 3);

                app.resultStoragePath = recordPathway(app);

                switch selection
                    case 'Analyze Separately'

                        app.analysismode = 'separate';                        

                    case 'Combine Files'

                        app.analysismode = 'combine';                      

                    case 'I need reorganize.'
                        return
                end
            else

                app.analysismode = 'single';

            end


            app.resultStoragePath = recordPathway(app);

            app.TabGroup.SelectedTab = app.ResultDefult_2;
            app.complete.Visible ='off';
            app.ProgressFig.Visible = "on";

            analysisMain(app,app.analysismode);

            app.ProgressFig.Visible = 'off';
            app.complete.Visible = 'on';
            app.AnalysisProgressLabel.Text = 'Complete!';

            unlock(app)

            updateButtonStates(app)


        end

        % Button pushed function: DistanceSpeedButton
        function DistanceSpeedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DistanceSpeedTab;
            cla(app.speedVsTime);
            cla(app.positionVsTime);
            cla(app.MovementVsTime);

            % Path to position and speed .fig files
            if strcmp(app.analysismode, 'combine')
                app.DataFilesListBox.Enable = 'off';
                positionfig   = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', 'PositionVsTime_CombinedData.fig');
                speedfig      = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', 'SpeedVsTime_CombinedData.fig');
                cumulativefig = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', 'CumulativeDistance_CombinedData.fig');
            else
                app.DataFilesListBox.Enable = 'on';
                if numel(app.DataFilesListBox.Items) == 1
                    singleItem = app.DataFilesListBox.Items{1};
                    positionfig   = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['PositionVsTime_', singleItem, '.fig']);
                    speedfig      = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['SpeedVsTime_', singleItem, '.fig']);
                    cumulativefig = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['CumulativeDistance_', singleItem, '.fig']);
                else
                    if isempty(app.DataFilesListBox.Value)
                        app.DataFilesListBox.Value = app.DataFilesListBox.Items{1};
                    end
                    positionfig   = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['PositionVsTime_', app.DataFilesListBox.Value, '.fig']);
                    speedfig      = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['SpeedVsTime_', app.DataFilesListBox.Value, '.fig']);
                    cumulativefig = fullfile(app.resultStoragePath, 'DisplacementAndSpeed', ['CumulativeDistance_', app.DataFilesListBox.Value, '.fig']);
                end
            end


            hFig = open(positionfig);

            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.speedVsTime);
            axis(app.speedVsTime, 'tight');

            hFig = open(positionfig);
            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.speedVsTime);

            % Force fixed 30x30 window centered at (15,15)
            axis(app.speedVsTime, 'equal');   % equal scaling on both axes

            close(hFig);

            hFig = open(speedfig);
            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.positionVsTime);
            axis(app.positionVsTime, 'tight');
            set(app.positionVsTime, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
            close(hFig);

            hFig = open(cumulativefig);
            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.MovementVsTime);
            axis(app.MovementVsTime, 'tight');
            set(app.MovementVsTime, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
            close(hFig);
          
        end

        % Button pushed function: LowMobilityBoutsButton
        function LowMobilityBoutsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LowMobilityBoutsTab;
            cla(app.LBMplot);

            % Path to .fig and .txt files
            if strcmp(app.analysismode, 'combine')
                app.DataFilesListBox.Enable = 'off';
                figurePath = fullfile(app.resultStoragePath, 'LowMobilityBouts', 'LBM_CombinedData.fig');
                textPath = fullfile(app.resultStoragePath, 'LowMobilityBouts', 'LBM_CombinedData.txt');
            else
                app.DataFilesListBox.Enable = 'on';
                if numel(app.DataFilesListBox.Items) == 1
                    singleItem = app.DataFilesListBox.Items{1};
                    figurePath = fullfile(app.resultStoragePath, 'LowMobilityBouts', ['LBM_', singleItem, '.fig']);
                    textPath = fullfile(app.resultStoragePath, 'LowMobilityBouts', ['LBM_', singleItem, '.txt']);
                else
                    if isempty(app.DataFilesListBox.Value)
                        app.DataFilesListBox.Value = app.DataFilesListBox.Items{1};
                    end
                    figurePath = fullfile(app.resultStoragePath, 'LowMobilityBouts', ['LBM_', app.DataFilesListBox.Value, '.fig']);
                    textPath = fullfile(app.resultStoragePath, 'LowMobilityBouts', ['LBM_', app.DataFilesListBox.Value, '.txt']);
                end
            end

            % Plot figure
            hFig = open(figurePath);
            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.LBMplot);
            axis(app.LBMplot, 'tight');
            set(app.LBMplot, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
            close(hFig);

            % Load and display text result
            if isfile(textPath)
                fileContent = fileread(textPath);
                app.LMBtext.Text = fileContent;
            else
                app.LMBtext.Text = 'Result file not found.';
            end

        end

        % Button pushed function: TremorButton
        function TremorButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TremorTab;
            cla(app.TremorAxes);

            % Path to your .fig file
            if strcmp(app.analysismode, 'combine')
                app.DataFilesListBox.Enable = 'off';
                figurePath = fullfile(app.resultStoragePath, 'Tremor', 'PowerSpectralDensity_CombinedData.fig');
                textPath = fullfile(app.resultStoragePath, 'Tremor', 'Tremor_CombinedData.txt');
            else
                app.DataFilesListBox.Enable = 'on';
                if numel(app.DataFilesListBox.Items) == 1
                    singleItem = app.DataFilesListBox.Items{1}; % Get the first item
                    figurePath = fullfile(app.resultStoragePath, 'Tremor', ['PowerSpectralDensity_', singleItem, '.fig']);
                    textPath = fullfile(app.resultStoragePath, 'Tremor', ['Tremor_', singleItem, '.txt']);
                else
                    if isempty(app.DataFilesListBox.Value)
                        app.DataFilesListBox.Value = app.DataFilesListBox.Items{1};
                    end
                    figurePath = fullfile(app.resultStoragePath, 'Tremor', ['PowerSpectralDensity_', app.DataFilesListBox.Value, '.fig']);
                    textPath = fullfile(app.resultStoragePath, 'Tremor', ['Tremor_', app.DataFilesListBox.Value, '.txt']);
                end
            end


            hFig = open(figurePath);
            ax = findobj(hFig, 'Type', 'axes');
            % Assuming the handle to your GUI's axes is gui_axes
            copyobj(allchild(ax), app.TremorAxes);
            axis(app.TremorAxes, 'tight');
            set(app.TremorAxes, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
            close(hFig);

            fileContent = fileread(textPath);

            % Set the content to the Text property of app.Label
            app.TremorResultLabel.Text = fileContent;

        end

        % Button pushed function: AtaxiaButton
        function AtaxiaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AtaxiaTab;
            cla(app.AtaxiaAxes);


            if strcmp(app.analysismode, 'combine')
                app.DataFilesListBox.Enable = 'off';
                textPath = fullfile(app.resultStoragePath, 'Ataxia', 'Ataxia_Result_CombinedData.txt');
                figurePath = fullfile(app.resultStoragePath, 'Ataxia', '1_CombinedData.fig');
            else
                app.DataFilesListBox.Enable = 'on';
                if numel(app.DataFilesListBox.Items) == 1
                    singleItem = app.DataFilesListBox.Items{1};
                    textPath = fullfile(app.resultStoragePath, 'Ataxia', ['Ataxia_Result_', singleItem, '.txt']);
                    figurePath = fullfile(app.resultStoragePath, 'Ataxia', ['1_', singleItem, '.fig']);
                else
                    if isempty(app.DataFilesListBox.Value)
                        app.DataFilesListBox.Value = app.DataFilesListBox.Items{1};
                    end
                    textPath = fullfile(app.resultStoragePath, 'Ataxia', ['Ataxia_Result_', app.DataFilesListBox.Value, '.txt']);
                    figurePath = fullfile(app.resultStoragePath, 'Ataxia', ['1_', app.DataFilesListBox.Value, '.fig']);
                end
            end


            fileContent = fileread(textPath);

            lines = strsplit(fileContent, '\n');

            % app.SoG.Value = lines{1};

            app.averageMovementRatio.Text = lines{1};
            app.AtaxiaListBox.Items = lines(3:(end-1));

            if app.SurfacePlotCheckBox.Value == 1
                if isfile(figurePath) 
                    hFig = open(figurePath);
                    ax = findobj(hFig, 'Type', 'axes');
                    copyobj(allchild(ax), app.AtaxiaAxes);
                    axis(app.AtaxiaAxes, 'tight');
                    set(app.AtaxiaAxes, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
                    close(hFig);
                    app.AtaxiaAxes.Visible = 'on';
                else
                    app.AtaxiaAxes.Visible = 'off';
                end
            else
                app.AtaxiaAxes.Visible = 'off';
            end


        end

        % Value changed function: DataFilesListBox
        function DataFilesListBoxValueChanged(app, event)
                       
            if app.TabGroup.SelectedTab == app.DistanceSpeedTab
                app.DistanceSpeedButtonPushed();
            elseif app.TabGroup.SelectedTab == app.AtaxiaTab
                app.AtaxiaButtonPushed();
            elseif app.TabGroup.SelectedTab == app.LowMobilityBoutsTab
                app.LowMobilityBoutsButtonPushed();
            elseif app.TabGroup.SelectedTab ==app.TremorTab
                app.TremorButtonPushed();
            end

        end

        % Value changed function: AtaxiaListBox
        function AtaxiaListBoxValueChanged(app, event)
            cla(app.AtaxiaAxes);

            % Find the index of the selected item in the ListBox
            [~, index] = ismember(app.AtaxiaListBox.Value, app.AtaxiaListBox.Items);
            if strcmp(app.analysismode, 'combine')
                app.DataFilesListBox.Enable = 'off';
                figurePath = fullfile(app.resultStoragePath, 'Ataxia', [num2str(index), '_CombinedData.fig']);
            else
                app.DataFilesListBox.Enable = 'on';
                if numel(app.DataFilesListBox.Items) == 1
                    singleItem = app.DataFilesListBox.Items{1};
                    figurePath = fullfile(app.resultStoragePath, 'Ataxia', [num2str(index), '_', singleItem, '.fig']);
                else
                    if isempty(app.DataFilesListBox.Value)
                        app.DataFilesListBox.Value = app.DataFilesListBox.Items{1};
                    end
                    figurePath = fullfile(app.resultStoragePath, 'Ataxia', [num2str(index), '_', app.DataFilesListBox.Value, '.fig']);
                end
            end


            hFig = open(figurePath);
            ax = findobj(hFig, 'Type', 'axes');
            copyobj(allchild(ax), app.AtaxiaAxes);
            axis(app.AtaxiaAxes, 'tight');
            set(app.AtaxiaAxes, 'XLim', get(ax, 'XLim'), 'YLim', get(ax, 'YLim'));
            close(hFig);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            userSettings.AutoStop = app.AutoStopCheckBox.Value;
            userSettings.AutoStopSpinner = app.AutoStopSpinner.Value;
            userSettings.AutoStopUnit = app.AutoStopUnitDropDown.Value;

            userSettings.Discard = app.DiscardCheckBox.Value;
            userSettings.DiscardSpinner = app.DiscardSpinner.Value;
            userSettings.DiscardUnit = app.DiscardUnitDropDown.Value;

            userSettings.SampleRate = app.SampleFrequencyDropDown.Value;
            % userSettings.SampleRate = str2double(extractBefore(app.SampleFrequencyDropDown.Value," Hz"));

            userSettings.SelectedAnimal = app.SelectExperimentalAnimalButtonGroup.SelectedObject.Text;

            userSettings.fileNameHistory = app.FileNameHistory.Items;

            matFilePath = tempPath(app,'userSettings.mat');
            save(matFilePath, 'userSettings');

            % system('taskkill /F /IM phidget_launcher.exe');
            system('pkill -f python');


            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroupR = uitabgroup(app.GridLayout);
            app.RecordR = uitab(app.TabGroupR);
            app.RecordR.Title = 'Record';
            app.GridLayout4 = uigridlayout(app.RecordR);
            app.TextUpdate = uilabel(app.GridLayout4);
            app.TextUpdate.Text = '';
            app.AnalysisR = uitab(app.TabGroupR);
            app.AnalysisR.Title = 'Analysis';
            app.GridLayout5 = uigridlayout(app.AnalysisR);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.ResultDefult = uitab(app.TabGroup);
            app.ResultDefult.Title = ' ';
            app.GridLayout14 = uigridlayout(app.ResultDefult);
            app.ProgressLabel = uilabel(app.GridLayout14);
            app.ProgressLabel.Text = 'Loading Data ... ...';
            app.ResultDefult_2 = uitab(app.TabGroup);
            app.ResultDefult_2.Title = ' ';
            app.GridLayout14_2 = uigridlayout(app.ResultDefult_2);
            app.AnalysisProgressLabel = uilabel(app.GridLayout14_2);
            app.AnalysisProgressLabel.Text = 'Processing ... ...';
            app.complete = uiimage(app.GridLayout14_2);
            app.complete.ImageSource = fullfile(pathToMLAPP, 'complete.png');
            app.ProgressFig = uiimage(app.GridLayout14_2);
            app.ProgressFig.ImageSource = fullfile(pathToMLAPP, 'progress.gif');
            app.TremorTab = uitab(app.TabGroup);
            app.TremorTab.Title = 'Tremor';
            app.GridLayout10 = uigridlayout(app.TremorTab);
            app.TremorAxes = uiaxes(app.GridLayout10);
            title(app.TremorAxes, 'Power Spectral Density')
            xlabel(app.TremorAxes, 'Frequency')
            ylabel(app.TremorAxes, 'Power')
            zlabel(app.TremorAxes, 'Z')
            app.TremorAxes.SubtitleFontWeight = 'bold';
            app.TremorResultLabel = uilabel(app.GridLayout10);
            app.AtaxiaTab = uitab(app.TabGroup);
            app.AtaxiaTab.Title = 'Ataxia';
            app.GridLayout11 = uigridlayout(app.AtaxiaTab);
            app.AtaxiaAxes = uiaxes(app.GridLayout11);
            title(app.AtaxiaAxes, 'Rapid Movement Path')
            xlabel(app.AtaxiaAxes, 'X position')
            ylabel(app.AtaxiaAxes, 'Y position')
            zlabel(app.AtaxiaAxes, 'Z')
            app.AtaxiaAxes.SubtitleFontWeight = 'bold';
            app.averageMovementRatio = uilabel(app.GridLayout11);
            app.AtaxiaListBox = uilistbox(app.GridLayout11);
            app.AtaxiaListBox.Items = {''};
            app.AtaxiaListBox.ValueChangedFcn = createCallbackFcn(app, @AtaxiaListBoxValueChanged, true);
            app.AtaxiaListBox.Value = '';
            app.listBoxTitle = uilabel(app.GridLayout11);
            app.listBoxTitle.Text = 'Movement Ratio:';
            app.LowMobilityBoutsTab = uitab(app.TabGroup);
            app.LowMobilityBoutsTab.Title = 'Low Mobility Bouts';
            app.GridLayout12 = uigridlayout(app.LowMobilityBoutsTab);
            app.LBMplot = uiaxes(app.GridLayout12);
            title(app.LBMplot, 'Total Low Mobility Moments vs Time')
            xlabel(app.LBMplot, 'Time (s)')
            ylabel(app.LBMplot, 'Low Mobility Moments (s)')
            zlabel(app.LBMplot, 'Z')
            app.LBMplot.SubtitleFontWeight = 'bold';
            app.LMBtext = uilabel(app.GridLayout12);
            app.LMBtext.Text = ' ';
            app.DistanceSpeedTab = uitab(app.TabGroup);
            app.DistanceSpeedTab.Title = 'Distance Speed';
            app.GridLayout13 = uigridlayout(app.DistanceSpeedTab);
            app.positionVsTime = uiaxes(app.GridLayout13);
            title(app.positionVsTime, 'Speed Vs. Time')
            xlabel(app.positionVsTime, 'Time (s)')
            ylabel(app.positionVsTime, 'Speen (cm/s)')
            zlabel(app.positionVsTime, 'Z')
            app.positionVsTime.SubtitleFontWeight = 'bold';
            app.speedVsTime = uiaxes(app.GridLayout13);
            title(app.speedVsTime, 'Position Vs. Time')
            xlabel(app.speedVsTime, 'X position')
            ylabel(app.speedVsTime, 'Y position')
            zlabel(app.speedVsTime, 'Z')
            app.speedVsTime.XLim = [0 30];
            app.speedVsTime.YLim = [0 30];
            app.speedVsTime.XTick = [0 5 10 15 20 25 30];
            app.speedVsTime.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.speedVsTime.YTick = [0 5 10 15 20 25 30];
            app.speedVsTime.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.speedVsTime.SubtitleFontWeight = 'bold';
            app.MovementVsTime = uiaxes(app.GridLayout13);
            title(app.MovementVsTime, 'Cumulative Distance Vs. Time')
            xlabel(app.MovementVsTime, 'Time (s)')
            ylabel(app.MovementVsTime, 'Cumulative Distance (cm)')
            zlabel(app.MovementVsTime, 'Z')
            app.MovementVsTime.SubtitleFontWeight = 'bold';
            app.DataFilesListBox = uilistbox(app.GridLayout5);
            app.DataFilesListBox.Items = {};
            app.DataFilesListBox.ValueChangedFcn = createCallbackFcn(app, @DataFilesListBoxValueChanged, true);
            app.DataFilesListBox.Value = {};
            app.DataFileLabel = uilabel(app.GridLayout5);
            app.DataFileLabel.Text = 'Data File';
            app.TremorButton = uibutton(app.GridLayout5, 'push');
            app.TremorButton.ButtonPushedFcn = createCallbackFcn(app, @TremorButtonPushed, true);
            app.TremorButton.Text = 'Tremor';
            app.DistanceSpeedButton = uibutton(app.GridLayout5, 'push');
            app.DistanceSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @DistanceSpeedButtonPushed, true);
            app.DistanceSpeedButton.Text = 'Distance/Speed';
            app.LowMobilityBoutsButton = uibutton(app.GridLayout5, 'push');
            app.LowMobilityBoutsButton.ButtonPushedFcn = createCallbackFcn(app, @LowMobilityBoutsButtonPushed, true);
            app.LowMobilityBoutsButton.Text = 'Low Mobility Bouts';
            app.AtaxiaButton = uibutton(app.GridLayout5, 'push');
            app.AtaxiaButton.ButtonPushedFcn = createCallbackFcn(app, @AtaxiaButtonPushed, true);
            app.AtaxiaButton.Text = 'Ataxia';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = '';
            app.TabGroupL = uitabgroup(app.GridLayout);
            app.RecordL = uitab(app.TabGroupL);
            app.RecordL.Title = 'Record';
            app.GridLayout2 = uigridlayout(app.RecordL);
            app.StartRecordButton = uibutton(app.GridLayout2, 'state');
            app.StartRecordButton.ValueChangedFcn = createCallbackFcn(app, @StartRecordButtonValueChanged, true);
            app.StartRecordButton.Text = 'Start Record';
            app.AcquisitionSettingsPanel = uipanel(app.GridLayout2);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.GridLayout17 = uigridlayout(app.AcquisitionSettingsPanel);
            app.AutoStopCheckBox = uicheckbox(app.GridLayout17);
            app.AutoStopCheckBox.Tooltip = {'Set duration for automatic stop of data recording.'};
            app.AutoStopCheckBox.Text = 'Auto Stop';
            app.AutoStopSpinner = uispinner(app.GridLayout17);
            app.AutoStopSpinner.Step = 10;
            app.AutoStopSpinner.Limits = [0 Inf];
            app.AutoStopSpinner.Value = 30;
            app.AutoStopUnitDropDown = uidropdown(app.GridLayout17);
            app.AutoStopUnitDropDown.Items = {'minutes', 'hours'};
            app.AutoStopUnitDropDown.Value = 'minutes';
            app.DiscardCheckBox = uicheckbox(app.GridLayout17);
            app.DiscardCheckBox.Tooltip = {'Specify the amount of initial data to discard.'};
            app.DiscardCheckBox.Text = 'Discard first';
            app.DiscardSpinner = uispinner(app.GridLayout17);
            app.DiscardSpinner.Step = 10;
            app.DiscardSpinner.Limits = [0 Inf];
            app.DiscardSpinner.Value = 30;
            app.DiscardUnitDropDown = uidropdown(app.GridLayout17);
            app.DiscardUnitDropDown.Items = {'seconds', 'minutes'};
            app.DiscardUnitDropDown.Tooltip = {'Select unit (seconds or minutes) for initial data discard period.'};
            app.DiscardUnitDropDown.Value = 'seconds';
            app.ofdataLabel = uilabel(app.GridLayout17);
            app.ofdataLabel.Text = 'of data';
            app.SampleFrequencyLabel = uilabel(app.GridLayout17);
            app.SampleFrequencyLabel.Text = 'Sample Frequency:';
            app.SampleFrequencyDropDown = uidropdown(app.GridLayout17);
            app.SampleFrequencyDropDown.Items = {'125 Hz', '100 Hz', '83.33 Hz', '62.5 Hz', '50 Hz', '40 Hz', '33.33 Hz', '31.25 Hz', '25 Hz', '20 Hz', '16.67 Hz', '15.625 Hz', '12.5 Hz', '10 Hz', '8 Hz', '6.25 Hz', '5 Hz', '4 Hz', '3.33 Hz', '3.125 Hz', '2.5 Hz', '2 Hz', '1.67 Hz', '1.6 Hz', '1.25 Hz', '1 Hz'};
            app.SampleFrequencyDropDown.Value = '125 Hz';
            app.ExportSettingsPanel = uipanel(app.GridLayout2);
            app.ExportSettingsPanel.Title = 'Export Settings';
            app.GridLayout15 = uigridlayout(app.ExportSettingsPanel);
            app.OutputFileNameLabel = uilabel(app.GridLayout15);
            app.OutputFileNameLabel.Tooltip = {'Specify the name of the file to save recorded data.'};
            app.OutputFileNameLabel.Text = 'Output File Name: ';
            app.SelectOutputFolderButton = uibutton(app.GridLayout15, 'state');
            app.SelectOutputFolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectOutputFolderButtonValueChanged, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.customFileName = uieditfield(app.GridLayout15, 'text');
            app.NoteLabel_2 = uilabel(app.GridLayout15);
            app.NoteLabel_2.Text = 'Note:';
            app.Note = uitextarea(app.GridLayout15);
            app.FileNameHistory = uidropdown(app.GridLayout15);
            app.FileNameHistory.Items = {' '};
            app.FileNameHistory.ValueChangedFcn = createCallbackFcn(app, @FileListValueChanged, true);
            app.FileNameHistory.Value = ' ';
            app.OutputPathLabel = uilabel(app.GridLayout15);
            app.OutputPathLabel.Text = 'Path Unselected';
            app.SelectExperimentalAnimalButtonGroup = uibuttongroup(app.GridLayout15);
            app.SelectExperimentalAnimalButtonGroup.Tooltip = {''};
            app.SelectExperimentalAnimalButtonGroup.Title = 'Select Experimental Animal';
            app.RatButton = uiradiobutton(app.SelectExperimentalAnimalButtonGroup);
            app.RatButton.Text = 'Rat';
            app.RatButton.Value = true;
            app.MouseButton = uiradiobutton(app.SelectExperimentalAnimalButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.CalibrateButton = uibutton(app.GridLayout2, 'state');
            app.CalibrateButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateButtonValueChanged, true);
            app.CalibrateButton.Text = 'Start Calibration';
            app.AnalysisL = uitab(app.TabGroupL);
            app.AnalysisL.Title = 'Analysis';
            app.GridLayout3 = uigridlayout(app.AnalysisL);
            app.SOGPanel = uipanel(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.SOGPanel);
            app.AtaxiaCheckBox = uicheckbox(app.GridLayout7);
            app.AtaxiaCheckBox.Text = 'Ataxia';
            app.StraightnessofGaitThresholdcmLabel = uilabel(app.GridLayout7);
            app.StraightnessofGaitThresholdcmLabel.Text = 'Straightness of Gait Threshold (cm):';
            app.SoGThreshold = uispinner(app.GridLayout7);
            app.SoGThreshold.Value = 1;
            app.xDirectionMaximumAbsoluteValueofMovementSpinnerLabel = uilabel(app.GridLayout7);
            app.xDirectionMaximumAbsoluteValueofMovementSpinnerLabel.Text = 'x-Direction Maximum Absolute Value of Movement:';
            app.xMaximum = uispinner(app.GridLayout7);
            app.xMaximum.Value = 3;
            app.yDirectionMaximumAbsoluteValueofMovementSpinnerLabel = uilabel(app.GridLayout7);
            app.yDirectionMaximumAbsoluteValueofMovementSpinnerLabel.Text = 'y-Direction Maximum Absolute Value of Movement:';
            app.yMaximum = uispinner(app.GridLayout7);
            app.yMaximum.Value = 3;
            app.SurfacePlotCheckBox = uicheckbox(app.GridLayout7);
            app.SurfacePlotCheckBox.Text = 'Surface Plot';
            app.Panel_ = uipanel(app.GridLayout3);
            app.GridLayout8 = uigridlayout(app.Panel_);
            app.DistanceSpeedCheckBox = uicheckbox(app.GridLayout8);
            app.DistanceSpeedCheckBox.Text = 'Distance/Speed';
            app.StartAnalysisButton = uibutton(app.GridLayout3, 'state');
            app.StartAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @StartAnalysisButtonValueChanged, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout18 = uigridlayout(app.Panel);
            app.LowMobilityBoutsCheckBox = uicheckbox(app.GridLayout18);
            app.LowMobilityBoutsCheckBox.Text = 'Low Mobility Bouts';
            app.SpeedthresholdcmsEditFieldLabel = uilabel(app.GridLayout18);
            app.SpeedthresholdcmsEditFieldLabel.Text = 'Speed threshold (cm/s):';
            app.Speedthreshold = uieditfield(app.GridLayout18, 'numeric');
            app.Speedthreshold.Value = 2;
            app.DurationthresholdsEditFieldLabel = uilabel(app.GridLayout18);
            app.DurationthresholdsEditFieldLabel.Text = 'Duration threshold (s)';
            app.Durationthreshold = uieditfield(app.GridLayout18, 'numeric');
            app.Durationthreshold.Value = 5;
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout19 = uigridlayout(app.Panel_2);
            app.TremorCheckBox = uicheckbox(app.GridLayout19);
            app.TremorCheckBox.Text = 'Tremor';
            app.FrequencyRangeLabel = uilabel(app.GridLayout19);
            app.FrequencyRangeLabel.Text = 'Frequency Range (Hz)：';
            app.lowerTremorFrequency = uieditfield(app.GridLayout19, 'numeric');
            app.lowerTremorFrequency.Value = 3;
            app.toLabel = uilabel(app.GridLayout19);
            app.toLabel.Text = 'to';
            app.higherTremorFrequency = uieditfield(app.GridLayout19, 'numeric');
            app.higherTremorFrequency.Value = 8;
            app.Panel_3 = uipanel(app.GridLayout3);
            app.GridLayout20 = uigridlayout(app.Panel_3);
            app.FileList = uilistbox(app.GridLayout20);
            app.FileList.Items = {'Please import data file(s)'};
            app.FileList.ValueChangedFcn = createCallbackFcn(app, @FileListValueChanged, true);
            app.FileList.Value = 'Please import data file(s)';
            app.ImportDataButton = uibutton(app.GridLayout20, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.MoveUpButton = uibutton(app.GridLayout20, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.GridLayout20, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.RemoveButton = uibutton(app.GridLayout20, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.Panel_4 = uipanel(app.GridLayout3);
            app.GridLayout21 = uigridlayout(app.Panel_4);
            app.ResultOutputButton = uibutton(app.GridLayout21, 'state');
            app.ResultOutputButton.ValueChangedFcn = createCallbackFcn(app, @ResultOutputButtonValueChanged, true);
            app.ResultOutputButton.Text = 'Select Output Folder';
            app.ResultOutputLabel = uilabel(app.GridLayout21);
            app.ResultOutputLabel.Text = 'Path Unselected';
            app.OutputFolderNameEditFieldLabel = uilabel(app.GridLayout21);
            app.OutputFolderNameEditFieldLabel.Text = 'Output Folder Name:';
            app.OutputFolderNameEditField = uieditfield(app.GridLayout21, 'text');
            app.OperatingSystemLabel = uilabel(app.GridLayout);
            app.OperatingSystemLabel.Text = '';
            app.ModeSwitch = uibutton(app.GridLayout, 'state');
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Tooltip = {'Click this button to toggle between Record and Analysis modes.'};
            app.ModeSwitch.Text = {'Mode Switch '; '(Record)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenStride

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end